classdef fmon_h20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FMONRigWaterSetupLabel      matlab.ui.control.Label
        LeftValveEditFieldLabel     matlab.ui.control.Label
        LeftValveEditField          matlab.ui.control.NumericEditField
        RightValveEditFieldLabel    matlab.ui.control.Label
        RightValveEditField         matlab.ui.control.NumericEditField
        InitValveEditFieldLabel     matlab.ui.control.Label
        InitValveEditField          matlab.ui.control.NumericEditField
        CalibrateButton             matlab.ui.control.Button
        FlushTubesButton            matlab.ui.control.Button
        RewardPortCalibrationLabel  matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            RunProtocol('Stop')
        end

        % Button pushed function: FlushTubesButton
        function FlushTubesButtonPushed(app, event)
            
            % Set all valve times to 1 sec
            assignin('base', 'LeftValveTime', 1);  
            assignin('base', 'RightValveTime', 1);
            assignin('base', 'InitValveTime', 1);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')

        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
           
            % Set valve time based on text field
            assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);  
            assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMONRigWaterSetupLabel = uilabel(app.UIFigure);
            app.FMONRigWaterSetupLabel.Text = 'FMON Rig - Water Setup';
            app.LeftValveEditFieldLabel = uilabel(app.UIFigure);
            app.LeftValveEditFieldLabel.Text = 'Left Valve';
            app.LeftValveEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftValveEditField.Limits = [0.1 0.2];
            app.LeftValveEditField.Value = 0.1;
            app.RightValveEditFieldLabel = uilabel(app.UIFigure);
            app.RightValveEditFieldLabel.Text = 'Right Valve';
            app.RightValveEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightValveEditField.Limits = [0.1 0.2];
            app.RightValveEditField.Value = 0.1;
            app.InitValveEditFieldLabel = uilabel(app.UIFigure);
            app.InitValveEditFieldLabel.Text = 'Init. Valve';
            app.InitValveEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitValveEditField.Limits = [0.1 0.2];
            app.InitValveEditField.Value = 0.1;
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.FlushTubesButton = uibutton(app.UIFigure, 'push');
            app.FlushTubesButton.ButtonPushedFcn = createCallbackFcn(app, @FlushTubesButtonPushed, true);
            app.FlushTubesButton.Text = 'Flush Tubes';
            app.RewardPortCalibrationLabel = uilabel(app.UIFigure);
            app.RewardPortCalibrationLabel.Text = 'Reward Port Calibration';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmon_h20

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end