classdef fmon_prefs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FMONPreferencesLabel           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        H2OTab                         matlab.ui.container.Tab
        FMONRigWaterSetupLabel         matlab.ui.control.Label
        RewardPortCalibrationLabel     matlab.ui.control.Label
        LeftValveEditFieldLabel        matlab.ui.control.Label
        LeftValveEditField             matlab.ui.control.NumericEditField
        RightValveEditFieldLabel       matlab.ui.control.Label
        RightValveEditField            matlab.ui.control.NumericEditField
        InitValveEditFieldLabel        matlab.ui.control.Label
        InitValveEditField             matlab.ui.control.NumericEditField
        NotesTextAreaLabel             matlab.ui.control.Label
        NotesTextArea                  matlab.ui.control.TextArea
        CheckBox                       matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        FlushWaterTubesButton          matlab.ui.control.StateButton
        CalibrateValveTimingButton     matlab.ui.control.StateButton
        SessionInfoTab                 matlab.ui.container.Tab
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        TrainerEditFieldLabel          matlab.ui.control.Label
        TrainerEditField               matlab.ui.control.EditField
        MouseSexDropDown               matlab.ui.control.DropDown
        MouseSexDropDownLabel          matlab.ui.control.Label
        ThermistorTypeDropDown         matlab.ui.control.DropDown
        ThermistorTypeDropDownLabel    matlab.ui.control.Label
        MouseIDEditFieldLabel          matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.EditField
        InitialWeightEditFieldLabel    matlab.ui.control.Label
        InitialWeightEditField         matlab.ui.control.EditField
        MinITIEditFieldLabel           matlab.ui.control.Label
        MinITIEditField                matlab.ui.control.NumericEditField
        MaxITIEditFieldLabel           matlab.ui.control.Label
        MaxITIEditField                matlab.ui.control.NumericEditField
        PercentOmissionEditFieldLabel  matlab.ui.control.Label
        PercentOmissionEditField       matlab.ui.control.NumericEditField
        DrugDoseperkgEditFieldLabel    matlab.ui.control.Label
        DrugDoseperkgEditField         matlab.ui.control.EditField
        SideBiasDropDown               matlab.ui.control.DropDown
        SideBiasDropDownLabel          matlab.ui.control.Label
        SessionLengthEditFieldLabel    matlab.ui.control.Label
        SessionLengthEditField         matlab.ui.control.NumericEditField
        ProtocolDropDown               matlab.ui.control.DropDown
        ProtocolDropDownLabel          matlab.ui.control.Label
        TotalDrugDoseEditFieldLabel    matlab.ui.control.Label
        TotalDrugDoseEditField         matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        SessionInformationLabel        matlab.ui.control.Label
        ExperimentInformationLabel     matlab.ui.control.Label
        RunProtocolTab                 matlab.ui.container.Tab
        ConnecttoBonsaiButton          matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        BonsaiConnectionLabel          matlab.ui.control.Label
        ProtocolNameLabel              matlab.ui.control.Label
        RunProtocolButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        protocol = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %protocol = '';  % Initialize protocol variable to set it in 'Run Protocol' tab
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Researcher Info
            experimenter = app.ExperimenterEditField.Value;
            trainer = app.TrainerEditField.Value;
            
            % Mouse Info
            protocol_name = app.ProtocolDropDown.Value;
            mouse_id = app.MouseIDEditField.Value;
            mouse_sex = app.MouseSexDropDown.Value;
            initial_weight = app.InitialWeightEditField.Value;        
            thermistor_type = app.ThermistorTypeDropDown.Value;

            % Protocol Variables
            session_length = app.SessionLengthEditField.Value;
            min_iti = app.MinITIEditField.Value;
            max_iti = app.MaxITIEditField.Value;
            side_bias = app.SideBiasDropDown.Value;
            drug_dose = app.DrugDoseperkgEditField.Value;
            drug_dose_total = app.TotalDrugDoseEditField.Value;
            pct_omission = app.PercentOmissionEditField.Value;
                        
            % Error checks to ensure all fields are complete
            % Experimenter Info
            if isempty(experimenter)
                uialert(app.UIFigure, 'Please input Researcher name.', 'Error');
            elseif isempty(trainer)
                uialert(app.UIFigure, 'Please input Trainer name.', 'Error');
            % Mouse Info    
            elseif isempty(protocol_name)
                uialert(app.UIFigure, 'Please specify Protocol.', 'Error');
            elseif isempty(mouse_id)
                uialert(app.UIFigure, 'Please input Mouse ID.', 'Error');
            elseif isempty(mouse_sex)
                uialert(app.UIFigure, 'Please specify Mouse Sex.', 'Error');
            elseif isempty(initial_weight)
                uialert(app.UIFigure, 'Please input Initial Weight', 'Error');
            elseif isempty(thermistor_type)
                uialert(app.UIFigure, 'Please input Thermistor Type.', 'Error');
            % Protocol Info    
            elseif isempty(session_length)
                uialert(app.UIFigure, 'Please input Session Duration.', 'Error');             
            elseif isempty(min_iti)
                uialert(app.UIFigure, 'Please input Min ITI Duration.', 'Error');
            elseif isempty(max_iti)
                uialert(app.UIFigure, 'Please input Max ITI Duration.', 'Error');
            elseif isempty(drug_dose)
                uialert(app.UIFigure, 'Please input Drug Dose mg/kg.', 'Error');
            elseif isempty(drug_dose_total)
                uialert(app.UIFigure, 'Please input Total Drug Dose.', 'Error');    
            elseif isempty(pct_omission)
                uialert(app.UIFigure, 'Please input Percentage of Omission Trials.', 'Error');    
            else
            % If all fields are complete, save variables to base workspace
            assignin('base', 'protocol', protocol_name);
            assignin('base', 'mouse_id', mouse_id);
            assignin('base', 'mouse_sex', mouse_sex);
            assignin('base', 'initial_weight', initial_weight);
            assignin('base', 'thermistor_type', thermistor_type);
            assignin('base', 'experimenter', experimenter);
            assignin('base', 'trainer', trainer);
            assignin('base', 'session_length', session_length);
            assignin('base', 'min_iti', min_iti);
            assignin('base', 'max_iti', max_iti);
            assignin('base', 'side_bias', side_bias);
            assignin('base', 'drug_dose', drug_dose);
            assignin('base', 'drug_dose_total', drug_dose_total);
            assignin('base', 'pct_omission', pct_omission);
            end
            
            % Create subject (mouse id) folder for Bpod to store data
            folderName = 'D:\Bpod Local\Data\' + string(mouse_id);
            if ~exist(folderName, 'dir')
                mkdir(folderName);
            end
        end

        % Button pushed function: ConnecttoBonsaiButton
        function ConnecttoBonsaiButtonPushed(app, event)
            
            % Run Bonsai connect Python Script
            [status, commandOut] = system('C:\ProgramData\Anaconda3\python.exe D:\connect.py');
            %disp(status)
            
        end

        % Callback function
        function FlushTubesButtonPushed(app, event)
                        
            % Set all valve times to 1 sec
            assignin('base', 'LeftValveTime', 1);  
            assignin('base', 'RightValveTime', 1);
            assignin('base', 'InitValveTime', 1);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')
            
        end

        % Callback function
        function CalibrateButtonPushed(app, event)
                       
            % Set valve time based on text field
            assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);  
            assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            
            % Run water calibration protocol
            RunProtocol('Start', 'calibrate_h2o', 'FakeSubject')
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            
            RunProtocol('Stop')
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
                        
            % Run Bonsai connect Python Script
            [status, commandOut] = system('C:\ProgramData\Anaconda3\python.exe D:\disconnect.py');
            
        end

        % Value changed function: ProtocolDropDown
        function ProtocolDropDownValueChanged(app, event)
            value = app.ProtocolDropDown.Value;            
            % Set Variables based on protocol defaults
                switch value
                    case 'Trainer 1'
                        app.SessionLengthEditField.Value = 30;
                        app.MinITIEditField.Value = 0;
                        app.MaxITIEditField.Value = 0;
                        app.PercentOmissionEditField.Value = 0;
                        app.protocol = 'fmon_train';
                        app.ProtocolNameLabel.Text = 'Trainer 1';
                        app.RunProtocolButton.Text = 'Run Trainer 1';
                    case '100:0'
                        app.SessionLengthEditField.Value = 40;
                        app.MinITIEditField.Value = 1;
                        app.MaxITIEditField.Value = 15;
                        app.PercentOmissionEditField.Value = 10;
                        app.protocol = 'fmon_task';
                        app.ProtocolNameLabel.Text = '100:0';
                        app.RunProtocolButton.Text = 'Run 100:0';
                end
            
        end

        % Button pushed function: RunProtocolButton
        function RunProtocolButtonPushed(app, event)
            if isempty(app.ProtocolDropDown.Value)
                uialert(app.UIFigure, 'You must specify a protocol in the Session Info tab.', 'Error');
            else
            % Run selected Bpod protocol
                RunProtocol('Start', app.protocol, app.MouseIDEditField.Value)
            end
        end

        % Value changed function: FlushWaterTubesButton
        function FlushWaterTubesButtonValueChanged(app, event)
            % Update Button State
            value = app.FlushWaterTubesButton.Value;
            app.FlushWaterTubesButton.Text = 'Stop Flushing Tubes';
            
            % Set all water valve times to 1 sec (flush)
            assignin('base', 'LeftValveTime', 1);  
            assignin('base', 'RightValveTime', 1);
            assignin('base', 'InitValveTime', 1);
            
            % Run water calibration protocol
            if value == true
                RunProtocol('Start', 'calibrate_h2o', 'FakeSubject');  
            else
                RunProtocol('Stop');
                app.FlushWaterTubesButton.Text = 'Flush Water Tubes';                
            end
       
        end

        % Value changed function: CalibrateValveTimingButton
        function CalibrateValveTimingButtonValueChanged(app, event)
            % Update Button State
            value = app.CalibrateValveTimingButton.Value;
            app.CalibrateValveTimingButton.Text = 'Stop Calibration';
            
            % Set valve time based on text field
            if app.CheckBox.Value == 1
                assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);
            else
                assignin('base', 'LeftValveTime', 0);
            end
            
            if app.CheckBox_2.Value == 1
                assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            else
                assignin('base', 'RightValveTime', 0);
            end
            
            if app.CheckBox_3.Value == 1
                assignin('base', 'InitValveTime', app.InitValveEditField.Value);
            else
                assignin('base', 'InitValveTime', 0);
            end
            
            % Run water calibration protocol
            if value == true
                RunProtocol('Start', 'calibrate_h2o', 'FakeSubject');  
            else
                RunProtocol('Stop');
                app.CalibrateValveTimingButton.Text = 'Calibrate Water Valves';                
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % Set Valve Timings based on text fields. They May have been
            % set to zero if corresponding box was unchecked.
            assignin('base', 'LeftValveTime', app.LeftValveEditField.Value);
            assignin('base', 'RightValveTime', app.RightValveEditField.Value);
            assignin('base', 'InitValveTime', app.InitValveEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMONPreferencesLabel = uilabel(app.UIFigure);
            app.FMONPreferencesLabel.Tooltip = {'Your name'};
            app.FMONPreferencesLabel.Text = 'FMON Preferences';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.H2OTab = uitab(app.TabGroup);
            app.H2OTab.Title = 'H2O';
            app.FMONRigWaterSetupLabel = uilabel(app.H2OTab);
            app.FMONRigWaterSetupLabel.Text = 'FMON Rig - Water Setup';
            app.RewardPortCalibrationLabel = uilabel(app.H2OTab);
            app.RewardPortCalibrationLabel.Text = 'Reward Port Calibration';
            app.LeftValveEditFieldLabel = uilabel(app.H2OTab);
            app.LeftValveEditFieldLabel.Text = 'Left Valve';
            app.LeftValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.LeftValveEditField.Limits = [0.1 1];
            app.LeftValveEditField.Value = 0.1;
            app.RightValveEditFieldLabel = uilabel(app.H2OTab);
            app.RightValveEditFieldLabel.Text = 'Right Valve';
            app.RightValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.RightValveEditField.Limits = [0.1 1];
            app.RightValveEditField.Value = 0.1;
            app.InitValveEditFieldLabel = uilabel(app.H2OTab);
            app.InitValveEditFieldLabel.Text = 'Init. Valve';
            app.InitValveEditField = uieditfield(app.H2OTab, 'numeric');
            app.InitValveEditField.Limits = [0.1 1];
            app.InitValveEditField.Value = 0.1;
            app.NotesTextAreaLabel = uilabel(app.H2OTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.NotesTextArea = uitextarea(app.H2OTab);
            app.CheckBox = uicheckbox(app.H2OTab);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.H2OTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.H2OTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_3.Value = true;
            app.FlushWaterTubesButton = uibutton(app.H2OTab, 'state');
            app.FlushWaterTubesButton.ValueChangedFcn = createCallbackFcn(app, @FlushWaterTubesButtonValueChanged, true);
            app.FlushWaterTubesButton.Text = 'Flush Water Tubes';
            app.CalibrateValveTimingButton = uibutton(app.H2OTab, 'state');
            app.CalibrateValveTimingButton.ValueChangedFcn = createCallbackFcn(app, @CalibrateValveTimingButtonValueChanged, true);
            app.CalibrateValveTimingButton.Text = 'Calibrate Valve Timing';
            app.SessionInfoTab = uitab(app.TabGroup);
            app.SessionInfoTab.Title = 'Session Info';
            app.ExperimenterEditFieldLabel = uilabel(app.SessionInfoTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.SessionInfoTab, 'text');
            app.TrainerEditFieldLabel = uilabel(app.SessionInfoTab);
            app.TrainerEditFieldLabel.Text = 'Trainer';
            app.TrainerEditField = uieditfield(app.SessionInfoTab, 'text');
            app.MouseSexDropDown = uidropdown(app.SessionInfoTab);
            app.MouseSexDropDown.Items = {'Male', 'Female', ''};
            app.MouseSexDropDown.Value = '';
            app.MouseSexDropDownLabel = uilabel(app.SessionInfoTab);
            app.MouseSexDropDownLabel.Text = 'Mouse Sex';
            app.ThermistorTypeDropDown = uidropdown(app.SessionInfoTab);
            app.ThermistorTypeDropDown.Items = {'Old', 'New', ''};
            app.ThermistorTypeDropDown.Value = '';
            app.ThermistorTypeDropDownLabel = uilabel(app.SessionInfoTab);
            app.ThermistorTypeDropDownLabel.Text = 'Thermistor Type';
            app.MouseIDEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.SessionInfoTab, 'text');
            app.InitialWeightEditFieldLabel = uilabel(app.SessionInfoTab);
            app.InitialWeightEditFieldLabel.Text = 'Initial Weight';
            app.InitialWeightEditField = uieditfield(app.SessionInfoTab, 'text');
            app.InitialWeightEditField.Value = ' g';
            app.MinITIEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MinITIEditFieldLabel.Text = 'Min ITI';
            app.MinITIEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.MinITIEditField.Value = 1;
            app.MaxITIEditFieldLabel = uilabel(app.SessionInfoTab);
            app.MaxITIEditFieldLabel.Text = 'Max ITI';
            app.MaxITIEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.MaxITIEditField.Value = 5;
            app.PercentOmissionEditFieldLabel = uilabel(app.SessionInfoTab);
            app.PercentOmissionEditFieldLabel.Text = 'Percent Omission';
            app.PercentOmissionEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.PercentOmissionEditField.Value = 10;
            app.DrugDoseperkgEditFieldLabel = uilabel(app.SessionInfoTab);
            app.DrugDoseperkgEditFieldLabel.Text = 'Drug Dose per kg';
            app.DrugDoseperkgEditField = uieditfield(app.SessionInfoTab, 'text');
            app.DrugDoseperkgEditField.Value = ' mg/kg';
            app.SideBiasDropDown = uidropdown(app.SessionInfoTab);
            app.SideBiasDropDown.Items = {'None', 'Left', 'Right', '', ''};
            app.SideBiasDropDown.Value = 'None';
            app.SideBiasDropDownLabel = uilabel(app.SessionInfoTab);
            app.SideBiasDropDownLabel.Text = 'Side Bias';
            app.SessionLengthEditFieldLabel = uilabel(app.SessionInfoTab);
            app.SessionLengthEditFieldLabel.Text = 'Session Length';
            app.SessionLengthEditField = uieditfield(app.SessionInfoTab, 'numeric');
            app.SessionLengthEditField.Value = 30;
            app.ProtocolDropDown = uidropdown(app.SessionInfoTab);
            app.ProtocolDropDown.Items = {'Trainer 1', '100:0', ''};
            app.ProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @ProtocolDropDownValueChanged, true);
            app.ProtocolDropDown.Value = '';
            app.ProtocolDropDownLabel = uilabel(app.SessionInfoTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.TotalDrugDoseEditFieldLabel = uilabel(app.SessionInfoTab);
            app.TotalDrugDoseEditFieldLabel.Text = 'Total Drug Dose';
            app.TotalDrugDoseEditField = uieditfield(app.SessionInfoTab, 'text');
            app.TotalDrugDoseEditField.Value = ' mg';
            app.SaveButton = uibutton(app.SessionInfoTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SessionInformationLabel = uilabel(app.SessionInfoTab);
            app.SessionInformationLabel.Text = 'Session Information';
            app.ExperimentInformationLabel = uilabel(app.SessionInfoTab);
            app.ExperimentInformationLabel.Text = 'Experiment Information';
            app.RunProtocolTab = uitab(app.TabGroup);
            app.RunProtocolTab.Title = 'Run Protocol';
            app.ConnecttoBonsaiButton = uibutton(app.RunProtocolTab, 'push');
            app.ConnecttoBonsaiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoBonsaiButtonPushed, true);
            app.ConnecttoBonsaiButton.Text = {'Connect to Bonsai'; ''};
            app.DisconnectButton = uibutton(app.RunProtocolTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = {'Disconnect'; ''};
            app.BonsaiConnectionLabel = uilabel(app.RunProtocolTab);
            app.BonsaiConnectionLabel.Text = 'Bonsai Connection:';
            app.ProtocolNameLabel = uilabel(app.RunProtocolTab);
            app.ProtocolNameLabel.Text = 'ProtocolName';
            app.RunProtocolButton = uibutton(app.RunProtocolTab, 'push');
            app.RunProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @RunProtocolButtonPushed, true);
            app.RunProtocolButton.Text = 'Run Protocol';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmon_prefs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end