classdef fmos_prefs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        BasicInfoTab                   matlab.ui.container.Tab
        InitialWeightEditField         matlab.ui.control.EditField
        InitialWeightEditFieldLabel    matlab.ui.control.Label
        MouseIDEditField               matlab.ui.control.EditField
        MouseIDEditFieldLabel          matlab.ui.control.Label
        ThermistorTypeDropDownLabel    matlab.ui.control.Label
        ThermistorTypeDropDown         matlab.ui.control.DropDown
        MouseSexDropDownLabel          matlab.ui.control.Label
        MouseSexDropDown               matlab.ui.control.DropDown
        ProtocolDropDownLabel          matlab.ui.control.Label
        ProtocolDropDown               matlab.ui.control.DropDown
        TrainerEditField               matlab.ui.control.EditField
        TrainerEditFieldLabel          matlab.ui.control.Label
        ExperimenterEditField          matlab.ui.control.EditField
        ExperimenterEditFieldLabel     matlab.ui.control.Label
        ProtocolSettingsTab            matlab.ui.container.Tab
        SessionLengthEditField         matlab.ui.control.NumericEditField
        SessionLengthEditFieldLabel    matlab.ui.control.Label
        SideBiasDropDownLabel          matlab.ui.control.Label
        SideBiasDropDown               matlab.ui.control.DropDown
        DrugDoseEditField              matlab.ui.control.EditField
        DrugDoseEditFieldLabel         matlab.ui.control.Label
        PercentOmissionEditField       matlab.ui.control.NumericEditField
        PercentOmissionEditFieldLabel  matlab.ui.control.Label
        MaxITIEditField                matlab.ui.control.NumericEditField
        MaxITIEditFieldLabel           matlab.ui.control.Label
        MinITIEditField                matlab.ui.control.NumericEditField
        MinITIEditFieldLabel           matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        FMONPreferencesLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Mouse Info
            protocol = app.ProtocolDropDown.Value;
            mouse_id = app.MouseIDEditField.Value;
            mouse_sex = app.MouseSexDropDown.Value;
            initial_weight = app.InitialWeightEditField.Value;        
            thermistor_type = app.ThermistorTypeDropDown.Value;

            % Researcher Info
            experimenter = app.ExperimenterEditField.Value;
            trainer = app.TrainerEditField.Value;

            % Protocol Settings
            session_length = app.SessionLengthEditField.Value;
            min_iti = app.MinITIEditField.Value;
            max_iti = app.MaxITIEditField.Value;
            side_bias = app.SideBiasDropDown.Value;
            drug_dose = app.DrugDoseEditField.Value;
            pct_omission = app.PercentOmissionEditField.Value;
            
            % Export variables to workspace
            assignin('base', 'protocol', protocol);
            assignin('base', 'mouse_id', mouse_id);
            assignin('base', 'mouse_sex', mouse_sex);
            assignin('base', 'initial_weight', initial_weight);
            assignin('base', 'thermistor_type', thermistor_type);
            assignin('base', 'experimenter', experimenter);
            assignin('base', 'trainer', trainer);
            assignin('base', 'session_length', session_length);
            assignin('base', 'min_iti', min_iti);
            assignin('base', 'max_iti', max_iti);
            assignin('base', 'side_bias', side_bias);
            assignin('base', 'drug_dose', drug_dose);
            assignin('base', 'pct_omission', pct_omission);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMONPreferencesLabel = uilabel(app.UIFigure);
            app.FMONPreferencesLabel.Tooltip = {'Your name'};
            app.FMONPreferencesLabel.Text = 'FMON Preferences';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicInfoTab = uitab(app.TabGroup);
            app.BasicInfoTab.Title = 'Basic Info';
            app.ExperimenterEditFieldLabel = uilabel(app.BasicInfoTab);
            app.ExperimenterEditFieldLabel.Text = 'Experimenter';
            app.ExperimenterEditField = uieditfield(app.BasicInfoTab, 'text');
            app.TrainerEditFieldLabel = uilabel(app.BasicInfoTab);
            app.TrainerEditFieldLabel.Text = 'Trainer';
            app.TrainerEditField = uieditfield(app.BasicInfoTab, 'text');
            app.ProtocolDropDown = uidropdown(app.BasicInfoTab);
            app.ProtocolDropDown.Items = {'Trainer 1', 'DOI', ''};
            app.ProtocolDropDown.Value = 'Trainer 1';
            app.ProtocolDropDownLabel = uilabel(app.BasicInfoTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.MouseSexDropDown = uidropdown(app.BasicInfoTab);
            app.MouseSexDropDown.Items = {'Male', 'Female', ''};
            app.MouseSexDropDown.Value = 'Male';
            app.MouseSexDropDownLabel = uilabel(app.BasicInfoTab);
            app.MouseSexDropDownLabel.Text = 'Mouse Sex';
            app.ThermistorTypeDropDown = uidropdown(app.BasicInfoTab);
            app.ThermistorTypeDropDown.Items = {'Old', 'New'};
            app.ThermistorTypeDropDown.Value = 'Old';
            app.ThermistorTypeDropDownLabel = uilabel(app.BasicInfoTab);
            app.ThermistorTypeDropDownLabel.Text = 'Thermistor Type';
            app.MouseIDEditFieldLabel = uilabel(app.BasicInfoTab);
            app.MouseIDEditFieldLabel.Text = 'Mouse ID';
            app.MouseIDEditField = uieditfield(app.BasicInfoTab, 'text');
            app.InitialWeightEditFieldLabel = uilabel(app.BasicInfoTab);
            app.InitialWeightEditFieldLabel.Text = 'Initial Weight';
            app.InitialWeightEditField = uieditfield(app.BasicInfoTab, 'text');
            app.InitialWeightEditField.Value = ' g';
            app.ProtocolSettingsTab = uitab(app.TabGroup);
            app.ProtocolSettingsTab.Title = 'Protocol Settings';
            app.MinITIEditFieldLabel = uilabel(app.ProtocolSettingsTab);
            app.MinITIEditFieldLabel.Text = 'Min ITI';
            app.MinITIEditField = uieditfield(app.ProtocolSettingsTab, 'numeric');
            app.MinITIEditField.Value = 1;
            app.MaxITIEditFieldLabel = uilabel(app.ProtocolSettingsTab);
            app.MaxITIEditFieldLabel.Text = 'Max ITI';
            app.MaxITIEditField = uieditfield(app.ProtocolSettingsTab, 'numeric');
            app.MaxITIEditField.Value = 5;
            app.PercentOmissionEditFieldLabel = uilabel(app.ProtocolSettingsTab);
            app.PercentOmissionEditFieldLabel.Text = 'Percent Omission';
            app.PercentOmissionEditField = uieditfield(app.ProtocolSettingsTab, 'numeric');
            app.PercentOmissionEditField.Value = 0.2;
            app.DrugDoseEditFieldLabel = uilabel(app.ProtocolSettingsTab);
            app.DrugDoseEditFieldLabel.Text = 'Drug Dose';
            app.DrugDoseEditField = uieditfield(app.ProtocolSettingsTab, 'text');
            app.DrugDoseEditField.Value = ' mg/kg';
            app.SideBiasDropDown = uidropdown(app.ProtocolSettingsTab);
            app.SideBiasDropDown.Items = {'None', 'Left', 'Right', '', ''};
            app.SideBiasDropDown.Value = 'None';
            app.SideBiasDropDownLabel = uilabel(app.ProtocolSettingsTab);
            app.SideBiasDropDownLabel.Text = 'Side Bias';
            app.SessionLengthEditFieldLabel = uilabel(app.ProtocolSettingsTab);
            app.SessionLengthEditFieldLabel.Text = 'Session Length';
            app.SessionLengthEditField = uieditfield(app.ProtocolSettingsTab, 'numeric');
            app.SessionLengthEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmos_prefs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end